classdef RDV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RPanel              matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        Switch              matlab.ui.control.Switch
        radsEditFieldLabel  matlab.ui.control.Label
        radsEditField       matlab.ui.control.NumericEditField
        rads2Label          matlab.ui.control.Label
        aEditField          matlab.ui.control.NumericEditField
        LPanel              matlab.ui.container.Panel
        Button              matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        Label_5             matlab.ui.control.Label
        KnobL               matlab.ui.control.Knob
        Label_6             matlab.ui.control.Label
        Knob                matlab.ui.control.Knob
        ButtonDraw          matlab.ui.control.Button
        Label               matlab.ui.control.Label
        SwitchMode          matlab.ui.control.Switch
        Lamp                matlab.ui.control.Lamp
        Lampfile            matlab.ui.control.Lamp
        ButtonLI            matlab.ui.control.Button
        ButtonLD            matlab.ui.control.Button
        ButtonHI            matlab.ui.control.Button
        ButtonHD            matlab.ui.control.Button
        ButtonD             matlab.ui.control.Button
        ButtonI             matlab.ui.control.Button
        Label_4             matlab.ui.control.Label
        KnobH               matlab.ui.control.Knob
        ButtonSTD           matlab.ui.control.Button
        ButtonSTI           matlab.ui.control.Button
        Label_7             matlab.ui.control.Label
        KnobST              matlab.ui.control.Knob
        dBLabel             matlab.ui.control.Label
        dBEditField         matlab.ui.control.NumericEditField
        ButtonC             matlab.ui.control.Button
    end

    
    properties (Access = private)
        file % 数据文件
        t
        fsig
        auto
        mode % Description
        fc
        fd
    end
    
    methods (Access = private)
        
        function updatePlot (app)
            x = table2array(app.t(:,1));
            y = table2array(app.t(:,2));            
            frequency = 1/mean(diff(x));
            plotType = app.Switch.Value ;
            
            y=process(app,y,frequency);
            
            colorbar(app.UIAxes,'off');
            if strcmp(plotType,'采样信号')
                plot(app.UIAxes, x, y);
                xlabel(app.UIAxes,'时间(s)');
                ylabel(app.UIAxes,'信号强度');
                xlim(app.UIAxes,[-inf inf])
                ylim(app.UIAxes,[-inf inf])
                title(app.UIAxes,'采样信号');
            else
                lp=length(y);
                Y=fft(y);
                sig=abs(Y(1:ceil(lp/2)));%频谱信号强度
                f=linspace(0, frequency/2, ceil(lp/2));
                [~,loc]=max(sig);
                fmax=f(loc);
                if app.mode==1
                plot(app.UIAxes, f(sig>1e-4), sig(sig>1e-4));
                xlabel(app.UIAxes,'频率(Hz)');
                ylabel(app.UIAxes,'振幅');
                title(app.UIAxes,'频谱');
                xlim(app.UIAxes,[0,5*fmax]);
                ylim(app.UIAxes,[-inf inf]);
                app.fsig=[f',sig];
                app.ButtonC.Enable='on';
                elseif app.mode==2
                L=app.KnobST.Value;
                win=hann(L,"periodic");
                [s,f,ts]=spectrogram(y,win,L-1,L,frequency);
                s(20*log10((abs(s)))<app.dBEditField.Value)=0;
                %计算速度与加速度
                [~,fall]=max(abs(s(:,1:end)));
                fall=f(fall);
                Lf=floor(length(fall)/2);
                app.fd=fall(end-Lf+1:end)-fall(1:Lf);
                app.fd=mean(app.fd);
                app.fc=mean(fall);
                %画图
                imagesc(app.UIAxes,ts, f, 20*log10((abs(s))));
                %set(app.UIAxes,'YDir','Normal')
                axis(app.UIAxes,"xy");
                xlabel(app.UIAxes,'时间(s)');
                ylabel(app.UIAxes,'频率(Hz)');
                xlim(app.UIAxes,[-inf inf])
                ylim(app.UIAxes,[0,5*fmax])
                title(app.UIAxes,'时频谱');
                colorbar(app.UIAxes);
                app.ButtonC.Enable='on';
                else
                d = uiprogressdlg(app.UIFigure,'Message','数据处理中，请稍后！');
                [sst,f] = wsst(y,frequency);
                %[fridge,~] = wsstridge(sst,5,f,'NumRidges',1);
                d.Value = .25;
                d.Message = '正在生成图像，请稍后！';
                dw=pcolor(app.UIAxes,x,f,abs(sst));
                d.Value = .50;
                d.Message = '正在生成图像，请稍后！';
                dw.EdgeColor = 'none';
                xlim(app.UIAxes,[-inf inf])
                ylim(app.UIAxes,[0,5*fmax])
                xlabel(app.UIAxes,'时间(s)');
                ylabel(app.UIAxes,'频率(Hz)');
                d.Value=.75;
                title(app.UIAxes,'时频谱');
                colorbar(app.UIAxes);
                pause(10)
                d.Message='数据处理完成！';
                %计算速度与加速度
                [~,fall]=max(abs(sst(:,1:end)));
                fall=f(fall);
                Lf=floor(length(fall)/2);
                app.fd=fall(end-Lf+1:end)-fall(1:Lf);
                app.fd=mean(app.fd);
                app.fc=mean(fall);
                app.ButtonC.Enable='on';
                end
            end            
        end
        
        function result = process(app,y,frequency)
            window = app.Knob.Value;
            Vhighpass = app.KnobH.Value;
            Vlowpass = app.KnobL.Value;
            if Vlowpass>0
            y=lowpass(y,Vlowpass,frequency,"ImpulseResponse","auto","Steepness",0.9,"StopbandAttenuation",80);
            end
            if Vhighpass>0
            y=highpass(y,Vhighpass,frequency,"ImpulseResponse","auto","Steepness",0.9,"StopbandAttenuation",80);
            end
            if (window > 0)
                c = length(y);
                w = ones(size(y));
                p1 = floor(c*window/2);
                w(1:p1+1) = (-cos((0:p1)/p1*pi)+1)/2;
                w(end-p1:end) = (cos((0:p1)/p1*pi)+1)/2;
                y=w.*y;
            end
            result = y./max(abs(y)) ; 
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lampfile.Color=[0.5 0.5 0.5];
            app.Lamp.Color=[0.5 0.5 0.5];
            xlabel(app.UIAxes,'时间(s)');
            ylabel(app.UIAxes,'信号强度');
            title(app.UIAxes,'采样信号');
            app.Switch.Value='采样信号';
            app.ButtonDraw.Enable='off';
            app.Switch.Enable='off';
            app.SwitchMode.Enable='off';
            app.DropDown.Enable='off';
            app.Knob.Enable='off';
            app.KnobH.Enable='off';
            app.KnobL.Enable='off';
            app.ButtonD.Enable='off';
            app.ButtonI.Enable='off';
            app.ButtonLD.Enable='off';
            app.ButtonLI.Enable='off';
            app.ButtonHD.Enable='off';
            app.ButtonHI.Enable='off';
            app.KnobST.Enable='off';
            app.ButtonSTD.Enable='off';
            app.ButtonSTI.Enable='off';
            app.dBEditField.Enable='off';
            app.ButtonC.Enable='off';
            app.aEditField.Editable='off';
            app.radsEditField.Editable='off';
            app.aEditField.Enable='off';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [app.file,~]=uigetfile({'*.csv;*.xlsx','数据文件'});
            if app.file~=0
            app.t=readtable(app.file,'PreserveVariableNames',true);
            app.t.Properties.VariableNames{1}='time';
            app.t.Properties.VariableNames{2}='volt';
            app.Lampfile.Color='g';
            app.ButtonDraw.Enable='on';
            app.Switch.Enable='on';
            app.SwitchMode.Enable='on';
            app.DropDown.Enable='on';
            app.DropDown.Value='傅里叶分析';
            app.mode=1;
            app.Knob.Enable='on';
            app.KnobH.Enable='on';
            app.KnobL.Enable='on';
            app.ButtonD.Enable='on';
            app.ButtonI.Enable='on';
            app.ButtonLD.Enable='on';
            app.ButtonLI.Enable='on';
            app.ButtonHD.Enable='on';
            app.ButtonHI.Enable='on';
            end
        end

        % Button pushed function: ButtonDraw
        function ButtonDrawPushed(app, event)
            updatePlot(app);
        end

        % Value changed function: SwitchMode
        function SwitchModeValueChanged(app, event)
            value = app.SwitchMode.Value;
             if strcmp(value, 'On')
                app.auto = 1 ;
                app.ButtonDraw.Enable = 'off' ;
                app.Lamp.Color = [0 1 0] ;
            else
                app.auto = 0 ;
                app.ButtonDraw.Enable = 'on' ;
                app.Lamp.Color = [0.5 0.5 0.5] ;
             end
             if app.auto
                updatePlot(app)
             end
        end

        % Value changed function: Knob, KnobH, KnobL
        function KnobValueChanged(app, event)
            if app.auto
                updatePlot(app)
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            updatePlot(app);
        end

        % Button pushed function: ButtonD
        function ButtonDPushed(app, event)
            value=app.Knob.Value;
            if value>0.01
                app.Knob.Value=app.Knob.Value-0.01;
            else
                app.Knob.Value=0;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonLD
        function ButtonLDPushed(app, event)
            value=app.KnobL.Value;
            if value>1
                app.KnobL.Value=app.KnobL.Value-1;
            else
                app.KnobL.Value=0;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonHD
        function ButtonHDPushed(app, event)
            value=app.KnobH.Value;
            if value>1
                app.KnobH.Value=app.KnobH.Value-1;
            else
                app.KnobH.Value=0;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonI
        function ButtonIPushed(app, event)
            value=app.Knob.Value;
            if value<0.99
                app.Knob.Value=app.Knob.Value+0.01;
            else
                app.Knob.Value=1;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonLI
        function ButtonLIPushed(app, event)
            value=app.KnobL.Value;
            if value<499
                app.KnobL.Value=app.KnobL.Value+1;
            else
                app.KnobL.Value=500;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonHI
        function ButtonHIPushed(app, event)
            value=app.KnobH.Value;
            if value<4999
                app.KnobH.Value=app.KnobH.Value+1;
            else
                app.KnobH.Value=5000;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value,'傅里叶分析') 
                app.mode=1;
                app.KnobST.Enable='off';
                app.ButtonSTD.Enable='off';
                app.ButtonSTI.Enable='off';
                app.Knob.Enable='on';
                app.ButtonD.Enable='on';
                app.ButtonI.Enable='on';
                app.dBEditField.Enable='off';
                app.aEditField.Enable='off';
            elseif strcmp(value,'短时傅里叶分析')
                app.mode=2;
                app.Knob.Value=0;
                app.KnobST.Enable='on';
                app.ButtonSTD.Enable='on';
                app.ButtonSTI.Enable='on';
                app.dBEditField.Enable='on';
                app.aEditField.Enable='on';
                app.Knob.Enable='off';
                app.ButtonD.Enable='off';
                app.ButtonI.Enable='off';                
            else
                app.mode=3;
                app.Knob.Value=0;
                app.KnobST.Enable='off';
                app.ButtonSTD.Enable='off';
                app.ButtonSTI.Enable='off';
                app.dBEditField.Enable='off';
                app.aEditField.Enable='on';
                app.Knob.Enable='off';
                app.ButtonD.Enable='off';
                app.ButtonI.Enable='off';
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonSTD
        function ButtonSTDPushed(app, event)
            value=app.KnobST.Value;
            if value>1010
                app.KnobST.Value=app.KnobST.Value-10;
            else
                app.KnobST.Value=1000;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonSTI
        function ButtonSTIPushed(app, event)
            value=app.KnobST.Value;
            if value<4990
                app.KnobST.Value=app.KnobST.Value+10;
            else
                app.KnobST.Value=5000;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Value changed function: KnobST
        function KnobSTValueChanged(app, event)
            value = app.KnobST.Value;
            app.KnobST.Value=round(value);
            if app.KnobST.Value<1000
                app.KnobST.Value=1000;
            end
            if app.auto
                updatePlot(app)
            end
        end

        % Value changed function: dBEditField
        function dBEditFieldValueChanged(app, event)
            if app.auto
                updatePlot(app)
            end
        end

        % Button pushed function: ButtonC
        function ButtonCPushed(app, event)
            %计算角加速度与转速
            if app.mode==1
            [~,loc]=max(app.fsig(:,2));
            fmax=app.fsig(loc,1);
            app.radsEditField.Value=fmax*pi/4;
            elseif app.mode==2
            app.radsEditField.Value=app.fc*pi/4;
            app.aEditField.Value=2*app.fd*pi/(4*(table2array(app.t(end,1))-table2array(app.t(1,1))));
            else
            app.radsEditField.Value=app.fc*pi/4;
            app.aEditField.Value=2*app.fd*pi/(4*(table2array(app.t(end,1))-table2array(app.t(1,1))));
            end
            app.ButtonC.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Switch = uiswitch(app.RPanel, 'slider');
            app.Switch.Items = {'采样信号', '频谱'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '采样信号';
            app.radsEditFieldLabel = uilabel(app.RPanel);
            app.radsEditFieldLabel.Text = '转速rad/s';
            app.radsEditField = uieditfield(app.RPanel, 'numeric');
            app.rads2Label = uilabel(app.RPanel);
            app.rads2Label.Text = '角加速度rad/s^2';
            app.aEditField = uieditfield(app.RPanel, 'numeric');
            app.LPanel = uipanel(app.UIFigure);
            app.Button = uibutton(app.LPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '读取数据文件';
            app.DropDown = uidropdown(app.LPanel);
            app.DropDown.Items = {'傅里叶分析', '短时傅里叶分析', '小波分析'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '傅里叶分析';
            app.Label_5 = uilabel(app.LPanel);
            app.Label_5.Text = '低通滤波';
            app.KnobL = uiknob(app.LPanel, 'continuous');
            app.KnobL.Limits = [0 500];
            app.KnobL.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500];
            app.KnobL.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Label_6 = uilabel(app.LPanel);
            app.Label_6.Text = {'汉宁窗'; '(傅里叶分析)'};
            app.Knob = uiknob(app.LPanel, 'continuous');
            app.Knob.Limits = [0 1];
            app.Knob.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ButtonDraw = uibutton(app.LPanel, 'push');
            app.ButtonDraw.ButtonPushedFcn = createCallbackFcn(app, @ButtonDrawPushed, true);
            app.ButtonDraw.Text = '画图';
            app.Label = uilabel(app.LPanel);
            app.Label.Text = '自动更新';
            app.SwitchMode = uiswitch(app.LPanel, 'slider');
            app.SwitchMode.ValueChangedFcn = createCallbackFcn(app, @SwitchModeValueChanged, true);
            app.Lamp = uilamp(app.LPanel);
            app.Lampfile = uilamp(app.LPanel);
            app.ButtonLI = uibutton(app.LPanel, 'push');
            app.ButtonLI.ButtonPushedFcn = createCallbackFcn(app, @ButtonLIPushed, true);
            app.ButtonLI.Text = '+';
            app.ButtonLD = uibutton(app.LPanel, 'push');
            app.ButtonLD.ButtonPushedFcn = createCallbackFcn(app, @ButtonLDPushed, true);
            app.ButtonLD.Text = '-';
            app.ButtonHI = uibutton(app.LPanel, 'push');
            app.ButtonHI.ButtonPushedFcn = createCallbackFcn(app, @ButtonHIPushed, true);
            app.ButtonHI.Text = '+';
            app.ButtonHD = uibutton(app.LPanel, 'push');
            app.ButtonHD.ButtonPushedFcn = createCallbackFcn(app, @ButtonHDPushed, true);
            app.ButtonHD.Text = '-';
            app.ButtonD = uibutton(app.LPanel, 'push');
            app.ButtonD.ButtonPushedFcn = createCallbackFcn(app, @ButtonDPushed, true);
            app.ButtonD.Text = '-';
            app.ButtonI = uibutton(app.LPanel, 'push');
            app.ButtonI.ButtonPushedFcn = createCallbackFcn(app, @ButtonIPushed, true);
            app.ButtonI.Text = '+';
            app.Label_4 = uilabel(app.LPanel);
            app.Label_4.Text = '高通滤波';
            app.KnobH = uiknob(app.LPanel, 'continuous');
            app.KnobH.Limits = [0 5000];
            app.KnobH.MajorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000 4500 5000];
            app.KnobH.MajorTickLabels = {'0', '500', '1000', '1500', '2000', '2500', '3000', '3500', '4000', '4500', '5000'};
            app.KnobH.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ButtonSTD = uibutton(app.LPanel, 'push');
            app.ButtonSTD.ButtonPushedFcn = createCallbackFcn(app, @ButtonSTDPushed, true);
            app.ButtonSTD.Text = '-';
            app.ButtonSTI = uibutton(app.LPanel, 'push');
            app.ButtonSTI.ButtonPushedFcn = createCallbackFcn(app, @ButtonSTIPushed, true);
            app.ButtonSTI.Text = '+';
            app.Label_7 = uilabel(app.LPanel);
            app.Label_7.Text = {'汉宁窗'; '(短时傅里叶分析)'};
            app.KnobST = uiknob(app.LPanel, 'continuous');
            app.KnobST.Limits = [1000 5000];
            app.KnobST.MajorTicks = [1000 2000 3000 4000 5000];
            app.KnobST.ValueChangedFcn = createCallbackFcn(app, @KnobSTValueChanged, true);
            app.KnobST.Value = 1000;
            app.dBLabel = uilabel(app.LPanel);
            app.dBLabel.Text = '信号强度下限(dB)';
            app.dBEditField = uieditfield(app.LPanel, 'numeric');
            app.dBEditField.ValueChangedFcn = createCallbackFcn(app, @dBEditFieldValueChanged, true);
            app.ButtonC = uibutton(app.LPanel, 'push');
            app.ButtonC.ButtonPushedFcn = createCallbackFcn(app, @ButtonCPushed, true);
            app.ButtonC.Text = '计算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RDV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end